classdef state_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveasEditFieldLabel            matlab.ui.control.Label
        SaveasEditField                 matlab.ui.control.EditField
        pitchthetaEditFieldLabel        matlab.ui.control.Label
        pitchthetaEditField             matlab.ui.control.EditField
        rollphiEditFieldLabel           matlab.ui.control.Label
        rollphiEditField                matlab.ui.control.EditField
        yawpsiEditFieldLabel            matlab.ui.control.Label
        yawpsiEditField                 matlab.ui.control.EditField
        pitchvelQEditFieldLabel         matlab.ui.control.Label
        pitchvelQEditField              matlab.ui.control.EditField
        rollvelPEditFieldLabel          matlab.ui.control.Label
        rollvelPEditField               matlab.ui.control.EditField
        yawvelREditFieldLabel           matlab.ui.control.Label
        yawvelREditField                matlab.ui.control.EditField
        pitchaccelEditFieldLabel        matlab.ui.control.Label
        pitchaccelEditField             matlab.ui.control.EditField
        yawaccelEditFieldLabel          matlab.ui.control.Label
        yawaccelEditField               matlab.ui.control.EditField
        STWEditFieldLabel               matlab.ui.control.Label
        STWEditField                    matlab.ui.control.EditField
        elavationEditFieldLabel         matlab.ui.control.Label
        elavationEditField              matlab.ui.control.EditField
        nodesintegral1EditFieldLabel    matlab.ui.control.Label
        nodesintegral1EditField         matlab.ui.control.EditField
        nodesintegral2EditFieldLabel    matlab.ui.control.Label
        nodesintegral2EditField         matlab.ui.control.EditField
        degLabel                        matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        degsLabel                       matlab.ui.control.Label
        degsLabel_2                     matlab.ui.control.Label
        degsLabel_3                     matlab.ui.control.Label
        degs2Label                      matlab.ui.control.Label
        degs2Label_2                    matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        mdistanceofrefpointabovewatersurfaceLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        momentsduetosailEditFieldLabel  matlab.ui.control.Label
        momentsduetosailEditField       matlab.ui.control.EditField
        EditField2                      matlab.ui.control.EditField
        EditField3                      matlab.ui.control.EditField
    end

    
    properties (Access = public)
        CallingApp; % Description
        geo;
        state;
        lattice;
        ref;
        settings;
    end
    
    methods (Access = private)
        
        function results = update(app)
            app.pitchthetaEditField.Value = num2str(app.state.theta*180/pi);
            app.rollphiEditField.Value = num2str(app.state.phi*180/pi);
            app.yawpsiEditField.Value = num2str(app.state.betha*180/pi);
            app.pitchvelQEditField.Value = num2str(app.state.Q*180/pi);
            app.rollvelPEditField.Value = num2str(app.state.P*180/pi);
            app.yawvelREditField.Value = num2str(app.state.R*180/pi);
            app.pitchaccelEditField.Value = num2str(app.state.adot*180/pi);
            app.yawaccelEditField.Value = num2str(app.state.bdot*180/pi);
            app.STWEditField.Value = num2str(app.state.STW);
            app.elavationEditField.Value = num2str(app.state.ELA);
            if exist('app.state.integral1')==1
            app.nodesintegral1EditField.Value = num2str(app.state.integral1);
            app.nodesintegral2EditField.Value = num2str(app.state.integral2);
            else
                app.nodesintegral1EditField.Value = '30';
            app.nodesintegral2EditField.Value = '30';
            end
            try
            app.momentsduetosailEditField.Value = num2str(app.state.Ms(1,1));
            app.EditField2.Value = num2str(app.state.Ms(2,1));
            app.EditField3.Value = num2str(app.state.Ms(3,1));
            catch
                app.momentsduetosailEditField.Value = 'no moment defined';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, geo, state, lattice, ref, settings)
            app.CallingApp = mainapp;
            app.geo = geo;
            app.state = state;
            app.lattice = lattice;
            app.ref = ref;
            app.settings = settings;
            
            update(app);
        end

        % Value changed function: pitchthetaEditField
        function pitchthetaEditFieldValueChanged(app, event)
            value = app.pitchthetaEditField.Value;
            app.state.theta = str2double(value)*pi/180;
        end

        % Value changed function: rollphiEditField
        function rollphiEditFieldValueChanged(app, event)
            value = app.rollphiEditField.Value;
            app.state.phi = str2double(value)*pi/180;
        end

        % Value changed function: yawpsiEditField
        function yawpsiEditFieldValueChanged(app, event)
            value = app.yawpsiEditField.Value;
            app.state.betha = str2double(value)*pi/180;
        end

        % Value changed function: pitchvelQEditField
        function pitchvelQEditFieldValueChanged(app, event)
            value = app.pitchvelQEditField.Value;
            app.state.Q = str2double(value)*pi/180;
        end

        % Value changed function: rollvelPEditField
        function rollvelPEditFieldValueChanged(app, event)
            value = app.rollvelPEditField.Value;
            app.state.P = str2double(value)*pi/180;
        end

        % Value changed function: yawvelREditField
        function yawvelREditFieldValueChanged(app, event)
            value = app.yawvelREditField.Value;
            app.state.R = str2double(value)*pi/180;
        end

        % Value changed function: pitchaccelEditField
        function pitchaccelEditFieldValueChanged(app, event)
            value = app.pitchaccelEditField.Value;
            app.state.adot = str2double(value)*pi/180;
        end

        % Value changed function: yawaccelEditField
        function yawaccelEditFieldValueChanged(app, event)
            value = app.yawaccelEditField.Value;
            app.state.bdot = str2double(value)*pi/180;
        end

        % Value changed function: STWEditField
        function STWEditFieldValueChanged(app, event)
            value = app.STWEditField.Value;
            app.state.STW = str2double(value);
        end

        % Value changed function: elavationEditField
        function elavationEditFieldValueChanged(app, event)
            value = app.elavationEditField.Value;
            app.state.ELA = str2double(value);
        end

        % Value changed function: nodesintegral1EditField
        function nodesintegral1EditFieldValueChanged(app, event)
            value = app.nodesintegral1EditField.Value;
            app.state.integral1 = str2double(value);
        end

        % Value changed function: nodesintegral2EditField
        function nodesintegral2EditFieldValueChanged(app, event)
            value = app.nodesintegral2EditField.Value;
            app.state.integral2 = str2double(value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            cd(app.settings.sdir)
            tph_version=1;
            fname=app.SaveasEditField.Value;
            if isempty(fname)==1
                app.Label.Text = '+++ no name, not saved +++';
                cd(app.settings.hdir)
            else
                try
                    state = app.state;
                    save(fname,'state');
                    cd(app.settings.hdir)
                    
                    update_state(app.CallingApp,app.state);
                catch
                    app.Label.Text = '+++ no state +++';
                    cd(app.settings.hdir)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            update_state(app.CallingApp,app.state);
            
            delete(app);
        end

        % Value changed function: momentsduetosailEditField
        function momentsduetosailEditFieldValueChanged(app, event)
            value = app.momentsduetosailEditField.Value;
            app.state.Ms(1,1) = str2double(value);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.state.Ms(2,1) = str2double(value);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            app.state.Ms(3,1) = str2double(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.UIFigure, 'text');
            app.pitchthetaEditFieldLabel = uilabel(app.UIFigure);
            app.pitchthetaEditFieldLabel.Text = 'pitch (theta)';
            app.pitchthetaEditField = uieditfield(app.UIFigure, 'text');
            app.pitchthetaEditField.ValueChangedFcn = createCallbackFcn(app, @pitchthetaEditFieldValueChanged, true);
            app.rollphiEditFieldLabel = uilabel(app.UIFigure);
            app.rollphiEditFieldLabel.Text = 'roll (phi)';
            app.rollphiEditField = uieditfield(app.UIFigure, 'text');
            app.rollphiEditField.ValueChangedFcn = createCallbackFcn(app, @rollphiEditFieldValueChanged, true);
            app.yawpsiEditFieldLabel = uilabel(app.UIFigure);
            app.yawpsiEditFieldLabel.Text = 'yaw (psi)';
            app.yawpsiEditField = uieditfield(app.UIFigure, 'text');
            app.yawpsiEditField.ValueChangedFcn = createCallbackFcn(app, @yawpsiEditFieldValueChanged, true);
            app.pitchvelQEditFieldLabel = uilabel(app.UIFigure);
            app.pitchvelQEditFieldLabel.Text = 'pitch vel. (Q)';
            app.pitchvelQEditField = uieditfield(app.UIFigure, 'text');
            app.pitchvelQEditField.ValueChangedFcn = createCallbackFcn(app, @pitchvelQEditFieldValueChanged, true);
            app.rollvelPEditFieldLabel = uilabel(app.UIFigure);
            app.rollvelPEditFieldLabel.Text = 'roll vel. (P)';
            app.rollvelPEditField = uieditfield(app.UIFigure, 'text');
            app.rollvelPEditField.ValueChangedFcn = createCallbackFcn(app, @rollvelPEditFieldValueChanged, true);
            app.yawvelREditFieldLabel = uilabel(app.UIFigure);
            app.yawvelREditFieldLabel.Text = 'yaw vel. (R)';
            app.yawvelREditField = uieditfield(app.UIFigure, 'text');
            app.yawvelREditField.ValueChangedFcn = createCallbackFcn(app, @yawvelREditFieldValueChanged, true);
            app.pitchaccelEditFieldLabel = uilabel(app.UIFigure);
            app.pitchaccelEditFieldLabel.Text = 'pitch accel. ';
            app.pitchaccelEditField = uieditfield(app.UIFigure, 'text');
            app.pitchaccelEditField.ValueChangedFcn = createCallbackFcn(app, @pitchaccelEditFieldValueChanged, true);
            app.yawaccelEditFieldLabel = uilabel(app.UIFigure);
            app.yawaccelEditFieldLabel.Text = 'yaw accel.';
            app.yawaccelEditField = uieditfield(app.UIFigure, 'text');
            app.yawaccelEditField.ValueChangedFcn = createCallbackFcn(app, @yawaccelEditFieldValueChanged, true);
            app.STWEditFieldLabel = uilabel(app.UIFigure);
            app.STWEditFieldLabel.Text = 'STW ';
            app.STWEditField = uieditfield(app.UIFigure, 'text');
            app.STWEditField.ValueChangedFcn = createCallbackFcn(app, @STWEditFieldValueChanged, true);
            app.elavationEditFieldLabel = uilabel(app.UIFigure);
            app.elavationEditFieldLabel.Text = 'elavation';
            app.elavationEditField = uieditfield(app.UIFigure, 'text');
            app.elavationEditField.ValueChangedFcn = createCallbackFcn(app, @elavationEditFieldValueChanged, true);
            app.nodesintegral1EditFieldLabel = uilabel(app.UIFigure);
            app.nodesintegral1EditFieldLabel.Text = 'nodes integral 1';
            app.nodesintegral1EditField = uieditfield(app.UIFigure, 'text');
            app.nodesintegral1EditField.ValueChangedFcn = createCallbackFcn(app, @nodesintegral1EditFieldValueChanged, true);
            app.nodesintegral2EditFieldLabel = uilabel(app.UIFigure);
            app.nodesintegral2EditFieldLabel.Text = 'nodes integral 2';
            app.nodesintegral2EditField = uieditfield(app.UIFigure, 'text');
            app.nodesintegral2EditField.ValueChangedFcn = createCallbackFcn(app, @nodesintegral2EditFieldValueChanged, true);
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = '[deg.]';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = '[deg.]';
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = '[deg.]';
            app.degsLabel = uilabel(app.UIFigure);
            app.degsLabel.Text = '[deg./s]';
            app.degsLabel_2 = uilabel(app.UIFigure);
            app.degsLabel_2.Text = '[deg./s]';
            app.degsLabel_3 = uilabel(app.UIFigure);
            app.degsLabel_3.Text = '[deg./s]';
            app.degs2Label = uilabel(app.UIFigure);
            app.degs2Label.Text = '[deg./s^2]';
            app.degs2Label_2 = uilabel(app.UIFigure);
            app.degs2Label_2.Text = '[deg./s^2]';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '[m/s]';
            app.mdistanceofrefpointabovewatersurfaceLabel = uilabel(app.UIFigure);
            app.mdistanceofrefpointabovewatersurfaceLabel.Text = '[m]   -   distance of ref. point above water surface';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.momentsduetosailEditFieldLabel = uilabel(app.UIFigure);
            app.momentsduetosailEditFieldLabel.Text = 'moments due to sail';
            app.momentsduetosailEditField = uieditfield(app.UIFigure, 'text');
            app.momentsduetosailEditField.ValueChangedFcn = createCallbackFcn(app, @momentsduetosailEditFieldValueChanged, true);
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = state_edit(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end